classdef CDIO18 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        StartCamera         matlab.ui.control.Button
        CaptureImageButton  matlab.ui.control.Button
        StartVideoButton    matlab.ui.control.Button
        SaveButton          matlab.ui.control.Button
        EditFieldLabel      matlab.ui.control.Label
        Filename            matlab.ui.control.EditField
        DirectoryButton     matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Tab2                matlab.ui.container.Tab
    end

    methods (Access = private)

        % Button pushed function: StartCamera
        function StartCameraButtonPushed(app, event)
            fig = figure('Toolbar','none',...
                'Menubar', 'none',...
                'NumberTitle','Off',...
                'Name','My Preview Window');
            LSV = axes(fig);
            % Create the image object in which you want to display
            % the video preview data. Make the size of the image
            % object match the dimensions of the video frames.
            
            app.vidRes = app.vid.VideoResolution
            nBands =app.vid.NumberOfBands;
            hImage = image(LSV, zeros(app.vidRes(2), app.vidRes(1), nBands));
            
            % Display the video data.
            app.im = getsnapshot(app.vid);
            preview(app.vid, hImage);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            % title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            set(app.vid,'ReturnedColorSpace','rgb');
            app.im =  getsnapshot(app.vid);
            
            % % Display image and stretch to fill axes
            imshow(app.im, 'Parent', app.UIAxes)
        end

        % Button pushed function: StartVideoButton
        function StartVideoButtonPushed(app, event)
            vobj = app.vid;
            fileName = app.Filename.Value;
            
            if strcmp(app.StartVideoButton.Text,'Start Video') == 1
                % Change button Name
                app.StartVideoButton.Text = 'Stop Video';
                
                %initialize video object
                vobj.TimeOut = Inf;
                vobj.FrameGrabInterval = 1;
                vobj.LoggingMode = 'disk&memory';
                vobj.FramesPerTrigger = 1;
                vobj.TriggerRepeat = Inf;
                %                 vobj.TriggerFrameDelay = 5; % to skip the log frames
                
                % set the framerate of the video at the highes available
                %                 src = getselectedsource(vobj);
                %                 frameRates = set(src, 'FrameRate')
                %                 src.FrameRate = frameRates{1};
                %                 actualRate = str2num( frameRates{1} );
                
                % generate file name
                if strcmp(app.fileNameVerif,fileName) == 1
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                else
                    app.is = 0;
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                end
                
                % generate video file
                v = VideoWriter([dir, '.avi']);
                v.Quality = 50;
                v.FrameRate = 25;  % lower the value -> slow motion. Can be useful if the movement is to fast
                vobj.DiskLogger = v;
                
                % Select the source to use for acquisition.
                vobj.SelectedSourceName = 'input1';
                tic
                % Start acquisition
                start(vobj)
                
                
            else % the user wants to end video recording
                % generate file name
                if strcmp(app.fileNameVerif,fileName) == 1
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                else
                    app.is = 0;
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                end
                
                % Change button Name
                app.StartVideoButton.Text = 'Start Video';
                
                % stop video recording
                stop(vobj)
                % delete(vobj);
            end
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            app.directory = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % generate directory and update incremental value
            fileName = app.Filename.Value;
            if strcmp(app.fileNameVerif,fileName) == 1
                dir = strcat(app.directory,'\', fileName,'_', num2str(app.is),'.png');
                app.is = app.is+1
            else
                app.is = 0;
                dir = strcat(app.directory,'\', fileName,'_', num2str(app.is),'.png');
                app.is = app.is+1
            end           
            imwrite(app.im, dir, 'png');
            app.fileNameVerif = fileName;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.Units = 'pixels';
            app.StartCamera = uibutton(app.Tab, 'push');
            app.StartCamera.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCamera.Text = 'Start Camera';
            app.CaptureImageButton = uibutton(app.Tab, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.StartVideoButton = uibutton(app.Tab, 'push');
            app.StartVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoButtonPushed, true);
            app.StartVideoButton.Text = 'Start Video';
            app.SaveButton = uibutton(app.Tab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Filename = uieditfield(app.Tab, 'text');
            app.DirectoryButton = uibutton(app.Tab, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory';
            app.UIAxes = uiaxes(app.Tab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Units = 'pixels';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CDIO18()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end