classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadButton                    matlab.ui.control.Button
        ChooseframeSpinnerLabel       matlab.ui.control.Label
        ChooseframeSpinner            matlab.ui.control.Spinner
        AveragediameterofvesselsTextAreaLabel  matlab.ui.control.Label
        AvgDiameter                   matlab.ui.control.TextArea
        DensityofvesselTextAreaLabel  matlab.ui.control.Label
        DensityOfVessels              matlab.ui.control.TextArea
        AdvanceduserSwitchLabel       matlab.ui.control.Label
        AdvanceduserSwitch            matlab.ui.control.Switch
        SensitivityEditFieldLabel     matlab.ui.control.Label
        SensitivityEditField          matlab.ui.control.NumericEditField
        PixelremovalSliderLabel       matlab.ui.control.Label
        PixelremovalSlider            matlab.ui.control.Slider
        EvaluateDropDownLabel         matlab.ui.control.Label
        EvaluateDropDown              matlab.ui.control.DropDown
        PlayvideoDropDownLabel        matlab.ui.control.Label
        PlayvideoDropDown             matlab.ui.control.DropDown
        OrgImg                        matlab.ui.control.UIAxes
        EnhancedImg                   matlab.ui.control.UIAxes
        SegmentedImg                  matlab.ui.control.UIAxes
        SaveDropDownLabel             matlab.ui.control.Label
        SaveDropDown                  matlab.ui.control.DropDown
    end


    properties (Access = public)
        grayimg % Variable for the gray scale image
        sensitivity = 0.64; % Default value for the sensitivity
        pixelremoval = 10000; % Defalut value for how large groups of pixels that is removed
        frame = 1;
        video
        framerate
        enhvideo = struct('cdata',[]);
        segvideo = struct('cdata',[]);
        orgvideo
        pathname
    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Make the user choose a video from the hard drive
            [filename, app.pathname] = uigetfile( ...
                {'*.mov;*.MOV;*.avi;*.mpg;*.mpeg; *.png','Video Files (*.mov,*.MOV,*.avi*.mpg*.mpeg*.png)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a video file');
            
            % Add the folder in which the video/image is selected from
            % to the path in order to be able to read the file
            addpath(app.pathname);
            
            % Extract the file extension of the file
            [~,~, ext] = fileparts(filename);
            
            % If the loaded image is a .png, don't use split frames
            % but rather just load the image and convert it to greyscale
            if ext == '.png'
                img = imread(filename);
                app.grayimg = convert2gray(img);
                app.ChooseframeSpinner.Enable = 'off';
            else
                % Split video into frames
                [app.video,frames,~,~, app.framerate] = splitFrames(filename);
                img = app.video(app.frame).cdata;
                app.grayimg = convert2gray(img);
                app.ChooseframeSpinner.Enable = 'on';
            end
            
            % Remove axes in figure and plot original image
            cla(app.OrgImg,'reset')
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(app.grayimg, 'parent', app.OrgImg);
            
            % Enhance and segment the image to be able
            % to plot it in the figures
            enhancedimg = imageEnhancement(app.grayimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Plot enhanced and segmented images in their figures
            cla(app.SegmentedImg,'reset')
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
            enhancedimg = imageEnhancement(app.grayimg);
            cla(app.EnhancedImg,'reset')
            xlabel(app.EnhancedImg, []);
            ylabel(app.EnhancedImg, []);
            app.EnhancedImg.XAxis.TickLabels = {};
            app.EnhancedImg.YAxis.TickLabels = {};
            imshow(enhancedimg, 'parent', app.EnhancedImg);
            
            % Enable the other buttons/functions
            app.EvaluateDropDown.Enable = 'on';
            app.PlayvideoDropDown.Enable = 'on';
            app.AdvanceduserSwitch.Enable = 'on';
            app.SaveDropDown.Enable = 'on';
            
                
        end

        % Callback function
        function SegmentationButtonPushed(app, event)
            
            % Enhance and segment the chosen frame
            enhancedimg = imageEnhancement(app.grayimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
            % Calculate the average diameter of the vessels and
            % display it in the text area AvgDiameter
            averagediameter = sizeCalculation(segmentedimg);
            avgD = [num2str(averagediameter) ' pixels'];
            app.AvgDiameter.Value = avgD;
            
            % Calculate the density of vessel (via segmentation)
            density = 100*nnz(imcomplement(segmentedimg))/(size(segmentedimg,1)*size(segmentedimg,2));
            density = [num2str(density) ' %'];
            app.DensityOfVessels.Value = density;
        end

        % Callback function
        function DeBackersscoreButtonPushed(app, event)
            % Segment the gray scale image. Need to add if-statement
            % that checks if an image have been loaded before doing this,
            % see Segmentation button callback function for more
            % information
            enhancedimg = imageEnhancement(app.grayimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
            % Calculate the average diameter of the vessels and
            % display it in the text area AvgDiameter
            averagediameter = sizeCalculation(segmentedimg);
            avgD = [num2str(averagediameter) ' pixels'];
            app.AvgDiameter.Value = avgD;
            
            %Applying a grid to the image and displays it in the GUI
            imshow(segmentedimg, 'parent', app.SegmentedImg)
            [rows, columns, numberOfColorChannels] = size(segmentedimg);
            for row = 1 : round(size(segmentedimg,1)/4) : rows
                line([1, columns], [row, row], 'Color', 'r', 'parent', app.SegmentedImg);
            end
            for col = 1 : round(size(segmentedimg,2)/4) : columns
                line([col, col], [1, rows], 'Color', 'r', 'parent', app.SegmentedImg);
            end


        % Count how many vessels that are crossing the line
            % and display it in the density of vessels text area
            vessels_crossing_lines = autoCountVessels(segmentedimg,4);
            debackerscore = DeBackers(segmentedimg,4,vessels_crossing_lines,2);
            debackerscore = [num2str(debackerscore) ' vessels/mm (de Backer''s score)'];
            app.DensityOfVessels.Value = debackerscore;
        end

        % Callback function
        function EnhancedimageButtonPushed(app, event)
            
           % Enhance the image and display it in the enhanced image plot
            enhancedimg = imageEnhancement(app.grayimg);
            xlabel(app.EnhancedImg, []);
            ylabel(app.EnhancedImg, []);
            app.EnhancedImg.XAxis.TickLabels = {};
            app.EnhancedImg.YAxis.TickLabels = {};
            imshow(enhancedimg, 'parent', app.EnhancedImg);
        end

        % Value changed function: AdvanceduserSwitch
        function AdvanceduserSwitchValueChanged(app, event)
            toggleValue = app.AdvanceduserSwitch.Value;
            if ( strcmpi(toggleValue,'Yes') )
                app.SensitivityEditField.Enable = 'on';
                app.PixelremovalSlider.Enable = 'on';
            else
                app.SensitivityEditField.Enable = 'off';
                app.PixelremovalSlider.Value.Enable = 'off';
            end
            
        end

        % Value changed function: SensitivityEditField
        function SensitivityEditFieldValueChanged(app, event)
            value = app.SensitivityEditField.Value;
            app.sensitivity = value;
            
            enhancedimg = imageEnhancement(app.grayimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
        end

        % Value changed function: PixelremovalSlider
        function PixelremovalSliderValueChanged(app, event)
            value = app.PixelremovalSlider.Value;
            app.pixelremoval = value;
            
            enhancedimg = imageEnhancement(app.grayimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
        end

        % Value changed function: ChooseframeSpinner
        function ChooseframeSpinnerValueChanged(app, event)
            % Get value frome choose frame and change variable 
            % app.grayimg to that frame. If chosen frame is larger
            % than #frames in movie, set choosen frame to last frame
            value = app.ChooseframeSpinner.Value;
            if value > size(app.video,2)
                app.frame = size(app.video,2);
                app.ChooseframeSpinner.Value = size(app.video,2);
            else
                app.frame = value;
            end
            img = app.video(app.frame).cdata;
            app.grayimg = convert2gray(img);
            
            % Remove axes in figure and plot original image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(app.grayimg, 'parent', app.OrgImg);
            
            % Enhance the new frame so it can be plotted in "Enhanced Image"
            % and "Segmented Image"
            enhancedimg = imageEnhancement(app.grayimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
            % Remove axes and display it in Enhance image
            xlabel(app.EnhancedImg, []);
            ylabel(app.EnhancedImg, []);
            app.EnhancedImg.XAxis.TickLabels = {};
            app.EnhancedImg.YAxis.TickLabels = {};
            imshow(enhancedimg, 'parent', app.EnhancedImg);
        end

        % Value changed function: EvaluateDropDown
        function EvaluateDropDownValueChanged(app, event)
            value = app.EvaluateDropDown.Value;
            
            if strcmp(value,'De Backers score')
                % Segment the gray scale image. Need to add if-statement
                % that checks if an image have been loaded before doing this,
                % see Segmentation button callback function for more
                % information
                enhancedimg = imageEnhancement(app.grayimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                imshow(segmentedimg, 'parent', app.SegmentedImg);
                
                % Calculate the average diameter of the vessels and
                % display it in the text area AvgDiameter
                averagediameter = sizeCalculation(segmentedimg);
                avgD = [num2str(averagediameter) ' pixels'];
                app.AvgDiameter.Value = avgD;
                
                %Applying a grid to the image and displays it in the GUI
                imshow(enhancedimg, 'parent', app.EnhancedImg)
                [rows, columns, numberOfColorChannels] = size(enhancedimg);
                for row = 1 : round(size(enhancedimg,1)/4) : rows
                    line([1, columns], [row, row], 'Color', 'r', 'parent', app.EnhancedImg);
                end
                for col = 1 : round(size(segmentedimg,2)/4) : columns
                    line([col, col], [1, rows], 'Color', 'r', 'parent', app.EnhancedImg);
                end
            
                % Count how many vessels that are crossing the line
                % and display it in the density of vessels text area
                vessels_crossing_lines = autoCountVessels(segmentedimg,4);
                debackerscore = DeBackers(segmentedimg,4,vessels_crossing_lines,2);
                debackerscore = [num2str(debackerscore) ' vessels/mm (de Backer''s score)'];
                app.DensityOfVessels.Value = debackerscore;
                
            elseif strcmp(value,'Segmentation')
                % Need to add function that checks if an image have been loaded
                % before trying to segment. I have tried to do it below but it
                % doesn't work
                %if exist(app.grayimg) == 0
                %    f = msgbox('Image doesn''t exist. Use the load button to load a video and try again.');
                %end
                enhancedimg = imageEnhancement(app.grayimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                
                % Remove axes and plot segmented image
                xlabel(app.OrgImg, []);
                ylabel(app.OrgImg, []);
                app.OrgImg.XAxis.TickLabels = {};
                app.OrgImg.YAxis.TickLabels = {};
                imshow(segmentedimg, 'parent', app.SegmentedImg);
                
                % Remove axes and plot enhanced image (to removed
                % the grid from de Backer evaluation
                xlabel(app.OrgImg, []);
                ylabel(app.OrgImg, []);
                app.OrgImg.XAxis.TickLabels = {};
                app.OrgImg.YAxis.TickLabels = {};
                imshow(enhancedimg, 'parent', app.EnhancedImg);
                
                % Calculate the average diameter of the vessels and
                % display it in the text area AvgDiameter
                averagediameter = sizeCalculation(segmentedimg);
                avgD = [num2str(averagediameter) ' pixels'];
                app.AvgDiameter.Value = avgD;
                
                % Calculate the density of vessel (via segmentation)
                density = 100*nnz(imcomplement(segmentedimg))/(size(segmentedimg,1)*size(segmentedimg,2));
                density = [num2str(density) ' %'];
                app.DensityOfVessels.Value = density;
            end
            
            
        end

        % Value changed function: PlayvideoDropDown
        function PlayvideoDropDownValueChanged(app, event)
            value = app.PlayvideoDropDown.Value;
            
            % Remove tha axes from the figure
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            
            
            % Play original video
            if strcmp(value, 'Original video')
                if size(app.orgvideo,2) < 2
                    app.orgvideo = constructVideo(app.video);
                end
                implay(app.orgvideo)
                
                % Elseif play enhanced video
            elseif strcmp(value, 'Enhanced video')
                if size(app.enhvideo,2) == 1 % If size==1 -> create video
                    for i=1:size(app.video,2)
                        temp = convert2gray(app.video(i).cdata);
                        temp = imageEnhancement(temp);
                        app.enhvideo(i).cdata = temp;
                    end
                    app.enhvideo = constructVideo(app.enhvideo);
                end
                implay(app.enhvideo)
                
                % Elseif play segmented video
            elseif strcmp(value, 'Segmented video')
                if size(app.segvideo,2) == 1 % if size==1 -> create video
                    for i=1:size(app.video,2)
                        temp = convert2gray(app.video(i).cdata);
                        temp = imageEnhancement(temp);
                        temp = segmentImage(temp,0.64, 10000);
                        app.segvideo(i).cdata = temp;
                    end
                    app.segvideo = constructVideo(app.segvideo);
                end
                implay(app.segvideo)
            end


        end

        % Value changed function: SaveDropDown
        function SaveDropDownValueChanged(app, event)
            value = app.SaveDropDown.Value;
            
            % Jumps to the folder where the file was loaded from
            oldfolder = cd(app.pathname);
            
            if strcmp(value, 'Original image')
                imwrite(app.grayimg,'Original image', 'png')
            
            elseif strcmp(value, 'Enhanced image')
                enhancedimg = imageEnhancement(app.grayimg);
                imwrite(enhancedimg,'Enhanced image', 'png')
           
            elseif strcmp(value, 'Segmented image')
                enhancedimg = imageEnhancement(app.grayimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                imwrite(segmentedimg,'Segmented image', 'png')
            end
            
            % Jumps back to existing folder
            cd(oldfolder)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ChooseframeSpinnerLabel = uilabel(app.UIFigure);
            app.ChooseframeSpinnerLabel.Text = 'Choose frame';
            app.ChooseframeSpinner = uispinner(app.UIFigure);
            app.ChooseframeSpinner.Limits = [1 Inf];
            app.ChooseframeSpinner.ValueChangedFcn = createCallbackFcn(app, @ChooseframeSpinnerValueChanged, true);
            app.ChooseframeSpinner.Enable = 'off';
            app.ChooseframeSpinner.Value = 1;
            app.AveragediameterofvesselsTextAreaLabel = uilabel(app.UIFigure);
            app.AveragediameterofvesselsTextAreaLabel.Text = {'Average diameter'; 'of vessels'};
            app.AvgDiameter = uitextarea(app.UIFigure);
            app.DensityofvesselTextAreaLabel = uilabel(app.UIFigure);
            app.DensityofvesselTextAreaLabel.Text = {'Density of'; 'vessel'};
            app.DensityOfVessels = uitextarea(app.UIFigure);
            app.AdvanceduserSwitchLabel = uilabel(app.UIFigure);
            app.AdvanceduserSwitchLabel.Text = 'Advanced user';
            app.AdvanceduserSwitch = uiswitch(app.UIFigure, 'slider');
            app.AdvanceduserSwitch.Items = {'Yes', 'No'};
            app.AdvanceduserSwitch.ValueChangedFcn = createCallbackFcn(app, @AdvanceduserSwitchValueChanged, true);
            app.AdvanceduserSwitch.Enable = 'off';
            app.AdvanceduserSwitch.Value = 'No';
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensitivityEditField.Limits = [0 1];
            app.SensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.SensitivityEditField.Enable = 'off';
            app.SensitivityEditField.Value = 0.64;
            app.PixelremovalSliderLabel = uilabel(app.UIFigure);
            app.PixelremovalSliderLabel.Text = {'Pixel'; 'removal'};
            app.PixelremovalSlider = uislider(app.UIFigure);
            app.PixelremovalSlider.Limits = [0 50000];
            app.PixelremovalSlider.ValueChangedFcn = createCallbackFcn(app, @PixelremovalSliderValueChanged, true);
            app.PixelremovalSlider.Enable = 'off';
            app.PixelremovalSlider.Value = 10000;
            app.EvaluateDropDownLabel = uilabel(app.UIFigure);
            app.EvaluateDropDownLabel.Text = 'Evaluate';
            app.EvaluateDropDown = uidropdown(app.UIFigure);
            app.EvaluateDropDown.Items = {'', 'De Backers score', 'Segmentation'};
            app.EvaluateDropDown.ValueChangedFcn = createCallbackFcn(app, @EvaluateDropDownValueChanged, true);
            app.EvaluateDropDown.Enable = 'off';
            app.EvaluateDropDown.Value = '';
            app.PlayvideoDropDownLabel = uilabel(app.UIFigure);
            app.PlayvideoDropDownLabel.Text = 'Play video';
            app.PlayvideoDropDown = uidropdown(app.UIFigure);
            app.PlayvideoDropDown.Items = {'Original video', 'Enhanced video', 'Segmented video', 'Option 4'};
            app.PlayvideoDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayvideoDropDownValueChanged, true);
            app.PlayvideoDropDown.Enable = 'off';
            app.PlayvideoDropDown.Value = 'Original video';
            app.OrgImg = uiaxes(app.UIFigure);
            title(app.OrgImg, 'Original Image')
            app.OrgImg.XLim = [0 1];
            app.OrgImg.YLim = [0 1];
            app.OrgImg.ZLim = [0 1];
            app.OrgImg.CLim = [0 1];
            app.OrgImg.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.OrgImg.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.OrgImg.ZTick = [0 0.5 1];
            app.EnhancedImg = uiaxes(app.UIFigure);
            title(app.EnhancedImg, 'Enhanced Image')
            app.EnhancedImg.XLim = [0 1];
            app.EnhancedImg.YLim = [0 1];
            app.EnhancedImg.ZLim = [0 1];
            app.EnhancedImg.CLim = [0 1];
            app.EnhancedImg.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.EnhancedImg.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.EnhancedImg.ZTick = [0 0.5 1];
            app.SegmentedImg = uiaxes(app.UIFigure);
            title(app.SegmentedImg, 'Segmented Image')
            app.SegmentedImg.XLim = [0 1];
            app.SegmentedImg.YLim = [0 1];
            app.SegmentedImg.ZLim = [0 1];
            app.SegmentedImg.CLim = [0 1];
            app.SegmentedImg.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SegmentedImg.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SegmentedImg.ZTick = [0 0.5 1];
            app.SaveDropDownLabel = uilabel(app.UIFigure);
            app.SaveDropDownLabel.Text = 'Save';
            app.SaveDropDown = uidropdown(app.UIFigure);
            app.SaveDropDown.Items = {'', 'Original image', 'Enhanced image', 'Segmented image'};
            app.SaveDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveDropDownValueChanged, true);
            app.SaveDropDown.Enable = 'off';
            app.SaveDropDown.Value = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end