classdef MIT18 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicroImagingTechnologyMITUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        StartCamera                     matlab.ui.control.Button
        CaptureImageButton              matlab.ui.control.Button
        StartVideoButton                matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        Filename                        matlab.ui.control.EditField
        DirectoryButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        VesselsTab                      matlab.ui.container.Tab
        LoadvesselsButton               matlab.ui.control.Button
        ChooseframeSpinnerLabel         matlab.ui.control.Label
        ChooseframeSpinner              matlab.ui.control.Spinner
        DensityofvesselsLabel           matlab.ui.control.Label
        DensityOfVessels                matlab.ui.control.EditField
        AdvanceduserSwitch              matlab.ui.control.Switch
        SensitivityEditFieldLabel       matlab.ui.control.Label
        SensitivityEditField            matlab.ui.control.NumericEditField
        PixelremovalSliderLabel         matlab.ui.control.Label
        PixelremovalSlider              matlab.ui.control.Slider
        EvaluateDropDownLabel           matlab.ui.control.Label
        EvaluateDropDown                matlab.ui.control.DropDown
        PlayvideoDropDownLabel          matlab.ui.control.Label
        PlayvideoDropDown               matlab.ui.control.DropDown
        SaveDropDownLabel               matlab.ui.control.Label
        SaveDropDown                    matlab.ui.control.DropDown
        AveragediameterLabel            matlab.ui.control.Label
        AvgDiameter                     matlab.ui.control.TextArea
        CalculatePPVButton              matlab.ui.control.Button
        TotalvesselsEditFieldLabel      matlab.ui.control.Label
        TotalvesselsEditField           matlab.ui.control.NumericEditField
        NoflowEditFieldLabel            matlab.ui.control.Label
        NoflowEditField                 matlab.ui.control.NumericEditField
        IntermittentflowEditFieldLabel  matlab.ui.control.Label
        IntermittentflowEditField       matlab.ui.control.NumericEditField
        PPVTextAreaLabel                matlab.ui.control.Label
        PPVTextArea                     matlab.ui.control.TextArea
        PVDresultTextAreaLabel          matlab.ui.control.Label
        PVDresultTextArea               matlab.ui.control.TextArea
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.EditField
        ImageregistrationButton         matlab.ui.control.Button
        OpticalflowButton               matlab.ui.control.Button
        AdvanceduserLabel               matlab.ui.control.Label
        Extraaxis                       matlab.ui.control.UIAxes
        SegmentedImg                    matlab.ui.control.UIAxes
        OrgImg                          matlab.ui.control.UIAxes
        EnhancedImg                     matlab.ui.control.UIAxes
        FirstframeSpinnerLabel          matlab.ui.control.Label
        FirstframeSpinner               matlab.ui.control.Spinner
        SecondframeSpinnerLabel         matlab.ui.control.Label
        SecondframeSpinner              matlab.ui.control.Spinner
        ApexesTab                       matlab.ui.container.Tab
        LoadapexesButton                matlab.ui.control.Button
        ChooseframeSpinner_2Label       matlab.ui.control.Label
        ChooseframeSpinnerA             matlab.ui.control.Spinner
        EvaluateDropDown_2Label         matlab.ui.control.Label
        EvaluateDropDownA               matlab.ui.control.DropDown
        PlayvideoDropDown_2Label        matlab.ui.control.Label
        PlayvideoDropDownA              matlab.ui.control.DropDown
        SaveDropDown_2Label             matlab.ui.control.Label
        SaveDropDownA                   matlab.ui.control.DropDown
        SNREditField_2Label             matlab.ui.control.Label
        SNREditFieldA                   matlab.ui.control.EditField
        AveragedistanceEditFieldLabel   matlab.ui.control.Label
        AveragedistanceEditField        matlab.ui.control.EditField
        DensityofvesselsLabel_2         matlab.ui.control.Label
        DensityofvesselsA               matlab.ui.control.EditField
        FirstframeSpinner_2Label        matlab.ui.control.Label
        FirstframeSpinnerA              matlab.ui.control.Spinner
        SecondframeSpinner_2Label       matlab.ui.control.Label
        SecondframeSpinnerA             matlab.ui.control.Spinner
        OrgImgA                         matlab.ui.control.UIAxes
        CentroidsA                      matlab.ui.control.UIAxes
        SegImgA                         matlab.ui.control.UIAxes
        ExtraA                          matlab.ui.control.UIAxes
        ImageregistrationButtonA        matlab.ui.control.Button
    end


    properties (Access = private)
        % Data acqusition variables
        vid %= videoinput('winvideo');
        is = 0; % incremental value for snapshot - used to rename picture file
        iv = 0; % incremental value for video - used to rename video file
        directory = pwd;
        fileNameVerif = ''; 
        im;
        vidRes;
        check = 0;
        
        % Vessels variables
        incO = 0; % Incremental value for saving original, 
        incE = 0; % original, enhanced, segmented 
        incS = 0; % and extra images from the 
        incExtra = 0; % save menu
        grayimg % Variable for the gray scale image
        sensitivity = 0.64; % Default value for the sensitivity
        pixelremoval = 10000; % Defalut value for how large groups of pixels that is removed
        frame = 1;
        video
        framerate
        enhvideo = struct('cdata',[]);
        segvideo = struct('cdata',[]);
        stabvideo = struct('cdata',[]);
        orgvideo
        pathname
        ppvcalc
        vesseldensity
        extraimage
        
        % Variables below are for Apex tab
        grayapex
        pathnameapex
        apexvideo
        apexframe = 1;
        extraapex
        segapexvideo = struct('cdata',[]);
        stabapexvideo = struct('cdata',[]);
        orgapexvideo
        incOA = 0; % Incremental value for saving original,
        incExtraA = 0; % original, enhanced, segmented
        incSA = 0; % and extra images from the
        
    end
    

    methods (Access = private)

        % Button pushed function: StartCamera
        function StartCameraButtonPushed(app, event)
            % Create a figure window.
            try
                app.vid = videoinput('pointgrey', 1,'F7_RGB_1288x964_Mode0');
                app.check = 1;
            catch
                app.vid = videoinput('winvideo', 1);
            end
            
            fig = figure('Toolbar','none',...
                'Menubar', 'none',...
                'NumberTitle','Off',...
                'Name','My Preview Window');
            LSV = axes(fig);
            % Create the image object in which you want to display
            % the video preview data. Make the size of the image
            % object match the dimensions of the video frames.
            
            app.vidRes = app.vid.VideoResolution
            nBands =app.vid.NumberOfBands;
            hImage = image(LSV, zeros(app.vidRes(2), app.vidRes(1), nBands));
            
            % Display the video data.
            set(app.vid,'ReturnedColorSpace','rgb');
            app.im = getsnapshot(app.vid);
            preview(app.vid, hImage);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            % Remove title, axis labels, and tick labels
            % title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            
            set(app.vid,'ReturnedColorSpace','rgb');
            app.im = getsnapshot(app.vid);
            
            % % Display image and stretch to fill axes
            app.UIAxes.XLim = [0, size(app.im,2)];
            app.UIAxes.YLim = [0, size(app.im,1)];
            imshow(app.im, 'Parent', app.UIAxes)
        end

        % Button pushed function: StartVideoButton
        function StartVideoButtonPushed(app, event)
            %Video = videoinput(app.vid2,deviceID,format)
            
            vobj = app.vid;
            fileName = app.Filename.Value;
            
            if strcmp(app.StartVideoButton.Text,'Start Video') == 1
                % Change button Name
                app.StartVideoButton.Text = 'Stop Video';
                
                %initialize video object
                vobj.TimeOut = Inf;
                vobj.FrameGrabInterval = 1;
                vobj.LoggingMode = 'disk&memory';
                vobj.FramesPerTrigger = 1;
                vobj.TriggerRepeat = Inf;
                %                 vobj.TriggerFrameDelay = 5; % to skip the log frames
                
                % set the framerate of the video at the highes available
                %                 src = getselectedsource(vobj);
                %                 frameRates = set(src, 'FrameRate')
                %                 src.FrameRate = frameRates{1};
                %                 actualRate = str2num( frameRates{1} );
                
                % generate file name
                if strcmp(app.fileNameVerif,fileName) == 1
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                else
                    app.is = 0;
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                end
                
                % generate video file
                v = VideoWriter([dir, '.avi']);
                v.Quality = 50;
                v.FrameRate = 25;  % lower the value -> slow motion. Can be useful if the movement is to fast
                vobj.DiskLogger = v;
                
                % Select the source to use for acquisition.
                if app.check == 0
                    vobj.SelectedSourceName = 'input1';
                else
                    vobj.SelectedSourceName = 'Default';
                end
                tic
                % Start acquisition
                start(vobj)
                
                
            else % the user wants to end video recording
                % generate file name
                if strcmp(app.fileNameVerif,fileName) == 1
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                else
                    app.is = 0;
                    dir = strcat(app.directory,'\', fileName,'_', num2str(app.is));
                    app.is = app.is+1;
                end
                
                % Change button Name
                app.StartVideoButton.Text = 'Start Video';
                
                % stop video recording
                stop(vobj)
                % delete(vobj);
            end
           
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            app.directory = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % generate directory and update incremental value
            fileName = app.Filename.Value;
            if strcmp(app.fileNameVerif,fileName) == 1
                dir = strcat(app.directory,'\', fileName, num2str(app.is),'.png');
                app.is = app.is+1;
            else
                app.is = 0;
                dir = strcat(app.directory,'\', fileName, num2str(app.is),'.png');
                app.is = app.is+1;
            end           
            imwrite(app.im, dir, 'png');            
            app.fileNameVerif = fileName;
        end

        % Close request function: MicroImagingTechnologyMITUIFigure
        function MicroImagingTechnologyMITUIFigureCloseRequest(app, event)
            fileName = app.Filename.Value;
            dir = strcat(app.directory,'\', fileName,'_', 'autosave_close','.png');
            if app.is == 0
            closepreview(app.vid);
            close all;
            delete(app)
            else
            imwrite(app.im, dir, 'png');
            closepreview(app.vid);
            close all;
            delete(app)
            end
        end

        % Button pushed function: LoadvesselsButton
        function LoadvesselsButtonPushed(app, event)
            % Make the user choose a video from the hard drive
            [filename, app.pathname] = uigetfile( ...
                {'*.mov;*.MOV;*.avi;*.mpg;*.mpeg; *.png','Video Files (*.mov,*.MOV,*.avi*.mpg*.mpeg*.png)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a video file');
            
            % Add the folder in which the video/image is selected from
            % to the path in order to be able to read the file
            addpath(app.pathname);
            
            % Extract the file extension of the file
            [~,~, ext] = fileparts(filename);
            
            % If the loaded image is a .png, don't use split frames
            % but rather just load the image and convert it to greyscale
            if strcmp(ext, '.png')
                img = imread(filename);
                remove_channel = removeChannel(img);
                app.grayimg = convert2gray(remove_channel);
                app.ChooseframeSpinner.Enable = 'off';
                app.ImageregistrationButton.Enable = 'off';
                app.FirstframeSpinner.Enable = 'off';
                app.SecondframeSpinner.Enable = 'off';
                app.PlayvideoDropDown.Enable = 'off';
                app.OpticalflowButton.Enable = 'off';
            else
                % Split video into frames
                [app.video,frames,~,~, app.framerate] = splitFrames(filename);
                img = app.video(app.frame).cdata;
                remove_channel = removeChannel(img);
                app.grayimg = convert2gray(remove_channel);
                
                % Enable the buttons that should be enabled only
                % if video file is loaded
                app.ChooseframeSpinner.Enable = 'on';
                app.ImageregistrationButton.Enable = 'on';
                app.FirstframeSpinner.Enable = 'on';
                app.SecondframeSpinner.Enable = 'on';
                app.PlayvideoDropDown.Enable = 'on';
                app.OpticalflowButton.Enable = 'on';
                
                % Specify the limits of the image registration spinnners
                app.FirstframeSpinner.Limits = [1, frames];
                app.SecondframeSpinner.Limits = [1, frames];
            end
            
            % Remove axes in figure and plot original image
            cla(app.OrgImg,'reset')
            title(app.OrgImg, 'Original image');
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XLim = [0, size(app.grayimg,2)];
            app.OrgImg.YLim = [0, size(app.grayimg,1)];
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            I = imshow(app.grayimg, 'parent', app.OrgImg);%, ...
%                 'XData', [1 app.OrgImg.Position(3)], ...
%                 'YData', [1 app.OrgImg.Position(4)]);
%             % Set limits of axes
%             app.OrgImg.XLim = [0 I.XData(2)];
%             app.OrgImg.YLim = [0 I.YData(2)];


            % Normalize, enhance and segment the image to be able
            % to plot it in the figures
            normalizedimg = normalizeVessels(app.grayimg);
            enhancedimg = imageEnhancement(normalizedimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Plot enhanced and segmented images in their figures
            cla(app.SegmentedImg,'reset')
            title(app.SegmentedImg, 'Segmented image');
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.SegmentedImg.XLim = [0, size(segmentedimg,2)];
            app.SegmentedImg.YLim = [0, size(segmentedimg,1)];
            app.SegmentedImg.XAxis.TickLabels = {};
            app.SegmentedImg.YAxis.TickLabels = {};
            I = imshow(segmentedimg, 'parent', app.SegmentedImg);%, ...
%                 'XData', [1 app.SegmentedImg.Position(3)], ...
%                 'YData', [1 app.SegmentedImg.Position(4)]);
%             % Set limits of axes
%             app.SegmentedImg.XLim = [0 I.XData(2)];
%             app.SegmentedImg.YLim = [0 I.YData(2)];
            
            % Plot enhanced image
            cla(app.EnhancedImg,'reset')
            title(app.EnhancedImg, 'Enhanced image');
            xlabel(app.EnhancedImg, []);
            ylabel(app.EnhancedImg, []);
            app.EnhancedImg.XLim = [0, size(enhancedimg,2)];
            app.EnhancedImg.YLim = [0, size(enhancedimg,1)];
            app.EnhancedImg.XAxis.TickLabels = {};
            app.EnhancedImg.YAxis.TickLabels = {};
            I = imshow(enhancedimg, 'parent', app.EnhancedImg);%, ...
%                 'XData', [1 app.EnhancedImg.Position(3)], ...
%                 'YData', [1 app.EnhancedImg.Position(4)]);
%             % Set limits of axes
%             app.EnhancedImg.XLim = [0 I.XData(2)];
%             app.EnhancedImg.YLim = [0 I.YData(2)];
            
            % Enable the other buttons/functions
            app.EvaluateDropDown.Enable = 'on';
            app.AdvanceduserSwitch.Enable = 'on';
            app.SaveDropDown.Enable = 'on';
            app.CalculatePPVButton.Enable = 'on';
            app.AdvanceduserSwitch.Enable = 'on';
            
            % Calculate the SNR of the image and display it in
            % the SNR text field
            SNR = snrCalculation(segmentedimg, app.grayimg);
            SNR =round(SNR, 1);
            snrstring = [num2str(SNR) ' dB'];
            app.SNREditField.Value = snrstring;


        end

        % Value changed function: AdvanceduserSwitch
        function AdvanceduserSwitchValueChanged(app, event)
            toggleValue = app.AdvanceduserSwitch.Value;
            if ( strcmpi(toggleValue,'Yes') )
                app.SensitivityEditField.Enable = 'on';
                app.PixelremovalSlider.Enable = 'on';
            else
                app.SensitivityEditField.Enable = 'off';
                app.PixelremovalSlider.Value.Enable = 'off';
            end
        end

        % Value changed function: SensitivityEditField
        function SensitivityEditFieldValueChanged(app, event)
            value = app.SensitivityEditField.Value;
            app.sensitivity = value;
            
            normalizedimg = normalizeVessels(app.grayimg);
            enhancedimg = imageEnhancement(normalizedimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
        end

        % Value changed function: PixelremovalSlider
        function PixelremovalSliderValueChanged(app, event)
            value = app.PixelremovalSlider.Value;
            app.pixelremoval = value;
            
            normalizedimg = normalizeVessels(app.grayimg);
            enhancedimg = imageEnhancement(normalizedimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
        end

        % Value changed function: ChooseframeSpinner
        function ChooseframeSpinnerValueChanged(app, event)
            % Get value frome choose frame and change variable 
            % app.grayimg to that frame. If chosen frame is larger
            % than #frames in movie, set choosen frame to last frame
            value = app.ChooseframeSpinner.Value;
            if value > size(app.video,2)
                app.frame = size(app.video,2);
                app.ChooseframeSpinner.Value = size(app.video,2);
            else
                app.frame = value;
            end
            img = app.video(app.frame).cdata;
            remove_channel = removeChannel(img);
            app.grayimg = convert2gray(remove_channel);
            
            % Remove axes in figure and plot original image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(app.grayimg, 'parent', app.OrgImg);


            % Enhance the new frame so it can be plotted in "Enhanced Image"
            % and "Segmented Image"
            normalizedimg = normalizeVessels(app.grayimg);
            enhancedimg = imageEnhancement(normalizedimg);
            segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
            
            % Remove axes and plot segmented image
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            imshow(segmentedimg, 'parent', app.SegmentedImg);
            
            % Remove axes and display it in Enhance image
            xlabel(app.EnhancedImg, []);
            ylabel(app.EnhancedImg, []);
            app.EnhancedImg.XAxis.TickLabels = {};
            app.EnhancedImg.YAxis.TickLabels = {};
            imshow(enhancedimg, 'parent', app.EnhancedImg);
            
            % Calculate the SNR of the image and display it in
            % the SNR text field
            SNR = snrCalculation(segmentedimg, app.grayimg);
            SNR =round(SNR, 1);
            snrstring = [num2str(SNR) ' dB'];
            app.SNREditField.Value = snrstring;
            
        end

        % Value changed function: EvaluateDropDown
        function EvaluateDropDownValueChanged(app, event)
            value = app.EvaluateDropDown.Value;
            
            if strcmp(value,'De Backer''s score')
                % Segment the gray scale image. Need to add if-statement
                % that checks if an image have been loaded before doing this,
                % see Segmentation button callback function for more
                % information
                normalizedimg = normalizeVessels(app.grayimg);
                enhancedimg = imageEnhancement(normalizedimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                imshow(segmentedimg, 'parent', app.SegmentedImg);
                
                % Calculate the average diameter of the vessels and
                % display it in the text area AvgDiameter
                averagediameter = sizeCalculation(segmentedimg);
                averagediameter =round(averagediameter, 1);
                avgD = [num2str(averagediameter) ' µm'];
                app.AvgDiameter.Value = avgD;
                
                %Applying a grid to the image and displays it in the GUI
                imshow(enhancedimg, 'parent', app.EnhancedImg)
                [rows, columns, ~] = size(enhancedimg);
                for row = 1 : round(size(enhancedimg,1)/4) : rows
                    line([1, columns], [row, row], 'Color', 'r', 'parent', app.EnhancedImg);
                end
                for col = 1 : round(size(segmentedimg,2)/4) : columns
                    line([col, col], [1, rows], 'Color', 'r', 'parent', app.EnhancedImg);
                end
            
                % Count how many vessels that are crossing the line
                % and display it in the density of vessels text area
                vessels_crossing_lines = autoCountVessels(segmentedimg,4);
                debackerscore = DeBackers(segmentedimg,4,vessels_crossing_lines,2);
                debackerscore=round(debackerscore, 1);
                debackerscore = [num2str(debackerscore) ' vessels/mm'];
                app.DensityOfVessels.Value = debackerscore;
                
            elseif strcmp(value,'Segmentation')
                % Need to add function that checks if an image have been loaded
                % before trying to segment. I have tried to do it below but it
                % doesn't work
                %if exist(app.grayimg) == 0
                %    f = msgbox('Image doesn''t exist. Use the load button to load a video and try again.');
                %end
                normalizedimg = normalizeVessels(app.grayimg);
                enhancedimg = imageEnhancement(normalizedimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                
                % Remove axes and plot segmented image
                xlabel(app.OrgImg, []);
                ylabel(app.OrgImg, []);
                app.OrgImg.XAxis.TickLabels = {};
                app.OrgImg.YAxis.TickLabels = {};
                imshow(segmentedimg, 'parent', app.SegmentedImg);
                
                % Remove axes and plot enhanced image (to removed
                % the grid from de Backer evaluation
                xlabel(app.OrgImg, []);
                ylabel(app.OrgImg, []);
                app.OrgImg.XAxis.TickLabels = {};
                app.OrgImg.YAxis.TickLabels = {};
                imshow(enhancedimg, 'parent', app.EnhancedImg);
                
                % Calculate the average diameter of the vessels and
                % display it in the text area AvgDiameter
                averagediameter = sizeCalculation(segmentedimg);
                averagediameter =round(averagediameter, 1);
                avgD = [num2str(averagediameter) ' µm'];
                app.AvgDiameter.Value = avgD;
                
                % Calculate the density of vessel (via segmentation)
                app.vesseldensity = 100*nnz(imcomplement(segmentedimg))/(size(segmentedimg,1)*size(segmentedimg,2));
                app.vesseldensity = round(app.vesseldensity, 1);
                density = [num2str(app.vesseldensity) ' %'];
                app.DensityOfVessels.Value = density;
                
            elseif strcmp(value, 'Structure detection')
                normalizedimg = normalizeVessels(app.grayimg);
                enhancedimg = imageEnhancement(normalizedimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                
                f = figure('visible', 'off');
                imshow(app.grayimg);
                hold on;
                boundaries = bwboundaries(segmentedimg,'holes');
                nbr_boundaries = size(boundaries, 1);
                for k = 1 : nbr_boundaries
                   	temp_boundary = boundaries{k};
                   	plot(temp_boundary(:,2), temp_boundary(:,1), 'r', 'LineWidth', 2);
                end
                hold off;
                saveas(gca, 'temp', 'png');
                app.extraimage = imread('temp.png');
                delete temp.png
                
                cla(app.Extraaxis,'reset')
                title(app.Extraaxis, 'Extra: Structure detection');
                app.Extraaxis.XLim = [0, size(app.extraimage,2)];
                app.Extraaxis.YLim = [0, size(app.extraimage,1)];
                app.Extraaxis.XAxis.TickLabels = {};
                app.Extraaxis.YAxis.TickLabels = {};
                imshow(app.extraimage, 'parent', app.Extraaxis);
                
                
            elseif strcmp(value, 'Representative frame')
                % Get a struct with all the segmented frames
                segframes = struct('cdata',[]);
                for i=1:size(app.video,2)
                    temp = convert2gray(app.video(i).cdata);
                    temp = normalizeVessels(temp);
                    temp = imageEnhancement(temp);
                    temp = segmentImage(temp,0.64, 10000);
                    segframes(i).cdata = temp;
                end
                % Determine the width and height of the frames
                vidWidth = size(segframes(1).cdata, 2);
                vidHeight = size(segframes(1).cdata, 1);
                
                % Calculate the optimal frame of the video and display it in Extraaxis
                app.extraimage = pixelAverage2(segframes, vidHeight, vidWidth, 0.4);
                cla(app.Extraaxis,'reset')
                title(app.Extraaxis, 'Extra: Representative frame');
                app.Extraaxis.XLim = [0, vidWidth];
                app.Extraaxis.YLim = [0, vidHeight];
                app.Extraaxis.XAxis.TickLabels = {};
                app.Extraaxis.YAxis.TickLabels = {};
                imshow(app.extraimage, 'parent', app.Extraaxis);
            end
            
        end

        % Value changed function: PlayvideoDropDown
        function PlayvideoDropDownValueChanged(app, event)
            value = app.PlayvideoDropDown.Value;
            
            % Remove the axes from the figure
            xlabel(app.OrgImg, []);
            ylabel(app.OrgImg, []);
            app.OrgImg.XAxis.TickLabels = {};
            app.OrgImg.YAxis.TickLabels = {};
            
            
            % Play original video
            if strcmp(value, 'Original video')
                if size(app.orgvideo,2) < 2
                    app.orgvideo = constructVideo(app.video);
                end
                implay(app.orgvideo)
                
                % Elseif play enhanced video
            elseif strcmp(value, 'Enhanced video')
                if size(app.enhvideo,2) == 1 % If size==1 -> create video
                    for i=1:size(app.video,2)
                        temp = convert2gray(app.video(i).cdata);
                        temp = normalizeVessels(temp);
                        temp = imageEnhancement(temp);
                        app.enhvideo(i).cdata = temp;
                    end
                    app.enhvideo = constructVideo(app.enhvideo);
                end
                implay(app.enhvideo)
                
                % Elseif play segmented video
            elseif strcmp(value, 'Segmented video')
                if size(app.segvideo,2) == 1 % if size==1 -> create video
                    for i=1:size(app.video,2)
                        temp = convert2gray(app.video(i).cdata);
                        temp = normalizeVessels(temp);
                        temp = imageEnhancement(temp);
                        temp = segmentImage(temp,0.64, 10000);
                        app.segvideo(i).cdata = temp;
                    end
                    app.segvideo = constructVideo(app.segvideo);
                end
                implay(app.segvideo)
                
                % Elseif play stabilized video
            elseif strcmp(value, 'Stabilized video')
                if size(app.stabvideo,2) == 1
                    app.stabvideo = videoStabilisation(app.video, 10, 5, 20);
                end
                implay(app.stabvideo)


            end
            
        end

        % Value changed function: SaveDropDown
        function SaveDropDownValueChanged(app, event)
            value = app.SaveDropDown.Value;
            
            % Jumps to the folder where the file was loaded from
            oldfolder = cd(app.pathname);
            
            if strcmp(value, 'Original image')
                imwrite(app.grayimg,['Original image' num2str(app.incO)], 'png')
                app.incO = app.incO+1;
                
            elseif strcmp(value, 'Enhanced image')
                normalizedimg = normalizeVessels(app.grayimg);
                enhancedimg = imageEnhancement(normalizedimg);
                imwrite(enhancedimg,['Enhanced image' num2str(app.incE)], 'png')
                app.incE = app.incE+1;
                
                
            elseif strcmp(value, 'Segmented image')
                normalizedimg = normalizeVessels(app.grayimg);
                enhancedimg = imageEnhancement(normalizedimg);
                segmentedimg = segmentImage(enhancedimg,app.sensitivity,app.pixelremoval);
                imwrite(segmentedimg,['Segmented image' num2str(app.incS)], 'png')
                app.incS = app.incS+1;
                
                
            elseif strcmp(value, 'Extra image')
                imwrite(app.extraimage,['Extra image' num2str(app.incExtra)], 'png')
                app.incExtra = app.incExtra+1;
                
            end
            
            % Jumps back to existing folder
            cd(oldfolder)
            
        end

        % Button pushed function: CalculatePPVButton
        function CalculatePPVButtonPushed(app, event)
            % Only calculate if total vessels > 1
           if app.TotalvesselsEditField.Value ~=1
               app.ppvcalc = PPV(app.TotalvesselsEditField.Value, app.NoflowEditField.Value, app.IntermittentflowEditField.Value);
               app.ppvcalc = round(app.ppvcalc, 1);
               ppvres = [num2str(app.ppvcalc) ' %'];
               app.PPVTextArea.Value = ppvres;
               
               % Calculate PVD from the calculated PVD
               pvdcalc = PVD(app.vesseldensity, app.ppvcalc);
               pvdcalc = round(pvdcalc/100, 1);
               pvdcalc = [num2str(pvdcalc) ' %'];
               app.PVDresultTextArea.Value = pvdcalc;
           end


        end

        % Callback function
        function LoadapexesButtonPushed(app, event)
            
        end

        % Button pushed function: ImageregistrationButton
        function ImageregistrationButtonPushed(app, event)
            app.extraimage = imageStabilisation(app.video, app.FirstframeSpinner.Value, app.SecondframeSpinner.Value);

            % Plot registered frame in Extra axis
            cla(app.Extraaxis,'reset')
            title(app.Extraaxis, 'Extra image: Registered frame');
            xlabel(app.Extraaxis, []);
            ylabel(app.Extraaxis, []);
            app.Extraaxis.XLim = [0, size(app.extraimage,2)];
            app.Extraaxis.YLim = [0, size(app.extraimage,1)];
            app.Extraaxis.XAxis.TickLabels = {};
            app.Extraaxis.YAxis.TickLabels = {};
            imshow(app.extraimage, 'parent', app.Extraaxis)
        end

        % Button pushed function: OpticalflowButton
        function OpticalflowButtonPushed(app, event)
            S = load('optical_flow_short');
            S = S.opflow_movie;
            
            implay(S)
%             hf = figure;
%             set(hf,'position',[480 640 640 480]);
% 
%             movie(hf,stabilised_video,1,8)
            
            
        end

        % Button pushed function: LoadapexesButton
        function LoadapexesButtonPushed2(app, event)
              % Make the user choose a video from the hard drive
            [filename, app.pathnameapex] = uigetfile( ...
                {'*.mov;*.MOV;*.avi;*.mpg;*.mpeg; *.png','Video Files (*.mov,*.MOV,*.avi*.mpg*.mpeg*.png)';
                '*.*',  'All Files (*.*)'}, ...
                'Select a video file');
            
            % Add the folder in which the video/image is selected from
            % to the path in order to be able to read the file
            addpath(app.pathnameapex);
            
            % Extract the file extension of the file
            [~,~, ext] = fileparts(filename);
            
            % If the loaded image is a .png, don't use split frames
            % but rather just load the image and convert it to greyscale
            if strcmp(ext, '.png')
                img = imread(filename);
                remove_channel = removeChannel(img);
                app.grayapex = convert2gray(remove_channel);
                app.ChooseframeSpinnerA.Enable = 'off';
                app.PlayvideoDropDownA.Enable = 'off';
                app.ImageregistrationButtonA.Enable = 'off';
                app.FirstframeSpinnerA.Enable = 'off';
                app.SecondframeSpinnerA.Enable = 'off';
            else
                % Split video into frames
                [app.apexvideo,frames, ~, ~, ~] = splitFrames(filename);
                img = app.apexvideo(app.apexframe).cdata;
                remove_channel = removeChannel(img);
                app.grayapex = convert2gray(remove_channel);
                
                % Enable the things that should only be enabled when video is loaded
                app.ChooseframeSpinnerA.Enable = 'on';
                app.PlayvideoDropDownA.Enable = 'on';
                app.ImageregistrationButtonA.Enable = 'on';
                app.FirstframeSpinnerA.Enable = 'on';
                app.SecondframeSpinnerA.Enable = 'on';
                
                %Specify the limits of the image registration spinners
                app.FirstframeSpinnerA.Limits = [1, frames];
                app.SecondframeSpinnerA.Limits = [1, frames];
            end
            
            
            % Remove axes in figure and plot original image of apexes
            cla(app.OrgImgA,'reset')
            title(app.OrgImgA, 'Original image');
            xlabel(app.OrgImgA, []);
            ylabel(app.OrgImgA, []);
            app.OrgImgA.XLim = [0, size(app.grayapex,2)];
            app.OrgImgA.YLim = [0, size(app.grayapex,1)];
            app.OrgImgA.XAxis.TickLabels = {};
            app.OrgImgA.YAxis.TickLabels = {};
            imshow(app.grayapex, 'parent', app.OrgImgA);
            
            % Get the centroids and display them in Centroids axis
            getCentroids(app.grayapex);
            centroids = imread('temp.png');
            delete temp.png
            
            cla(app.CentroidsA,'reset')
            title(app.CentroidsA, 'Centroids');
            xlabel(app.CentroidsA, []);
            ylabel(app.CentroidsA, []);
            app.CentroidsA.XLim = [0, size(centroids,2)];
            app.CentroidsA.YLim = [0, size(centroids,1)];
            app.CentroidsA.XAxis.TickLabels = {};
            app.CentroidsA.YAxis.TickLabels = {};
            imshow(centroids, 'parent', app.CentroidsA);
            
            % Get the segmented image and display it in segmented image
            normalizespot = normalizeApexes(app.grayapex);
            segmentedspot = segmentSpot(normalizespot);
            cla(app.SegImgA,'reset')
            title(app.SegImgA, 'Segmented image');
            xlabel(app.SegImgA, []);
            ylabel(app.SegImgA, []);
            app.SegImgA.XLim = [0, size(segmentedspot,2)];
            app.SegImgA.YLim = [0, size(segmentedspot,1)];
            app.SegImgA.XAxis.TickLabels = {};
            app.SegImgA.YAxis.TickLabels = {};
            imshow(segmentedspot, 'parent', app.SegImgA);
            
            % Enable all the other buttons/functions
            app.EvaluateDropDownA.Enable = 'on';
            app.SaveDropDownA.Enable = 'on';
            
            
            % Calculate the SNR of the image and display it in
            % the SNR text field
            SNR = snrCalculation(segmentedspot, app.grayapex);
            SNR = round(SNR, 1);
            snrstring = [num2str(SNR) ' dB'];
            app.SNREditFieldA.Value = snrstring;


        end

        % Value changed function: ChooseframeSpinnerA
        function ChooseframeSpinnerAValueChanged(app, event)
            % Get the value from choose frame spinner and change the images
            % to the value of the chosen frame. If the value chosen is greater
            % than the amount of frames, the chose value will be set to the last
            % frame
            
            value = app.ChooseframeSpinnerA.Value;
            if value > size(app.apexvideo,2)
                app.apexframe = size(app.apexvideo,2);
                app.ChooseframeSpinnerA.Value = app.apexframe;
            else
                app.apexframe = value;
            end
            
            img = app.apexvideo(app.apexframe).cdata;
            remove_channel = removeChannel(img);
            app.grayapex = convert2gray(remove_channel);
            
            % Display the new frame in axis
            imshow(app.grayapex, 'parent', app.OrgImgA)
            
            % Calculate the new SNR
            % THIS WILL BE DONE LATER
            
            % Get the centroids and segmented image and display them in the axises
            getCentroids(app.grayapex);
            centroids = imread('temp.png');
            delete temp.png
            imshow(centroids, 'parent', app.CentroidsA);
            
            normalizespot = normalizeApexes(app.grayapex);
            segmentedspot = segmentSpot(normalizespot);
            imshow(segmentedspot, 'parent', app.SegImgA);
            
            % Calculate the SNR of the image and display it in
            % the SNR text field
            SNR = snrCalculation(segmentedspot, app.grayapex);
            SNR = round(SNR, 1);
            snrstring = [num2str(SNR) ' dB'];
            app.SNREditFieldA.Value = snrstring;


        end

        % Value changed function: EvaluateDropDownA
        function EvaluateDropDownAValueChanged(app, event)
            value = app.EvaluateDropDownA.Value;
            
            % Generate the segmented image and use it to calculate the
            % average distance between the apexes
            if strcmp(value, 'Segmentation')
                normalizespot = normalizeApexes(app.grayapex);
                segmentedspot = segmentSpot(normalizespot);
                [~, measurements, ~] = constructLabels(segmentedspot,80);
                
                [~, mean_distance] = spotDistance(measurements);
                %Convert the unit from pixels to µm
                mean_distance = mean_distance/((size(segmentedspot,2)+size(segmentedspot,1))/2)*1000;
                mean_distance = round(mean_distance, 1);
                mean_distancestr = [num2str(mean_distance) ' µm'];
                app.AveragedistanceEditField.Value = mean_distancestr;
                
                % Calculate the density of vessels and display it
                % in the density of vessels field
                density = densityCalculation(segmentedspot);
                density = round(density, 1);
                densitystr = [num2str(density), ' %'];
                app.DensityofvesselsA.Value = densitystr;
                
                %Edge detection
            elseif strcmp(value, 'Structure detection')
                normalizespot = normalizeApexes(app.grayapex);
                segmentedspot = segmentSpot(normalizespot);
                f = figure('visible', 'off');
                imshow(app.grayapex);
                hold on;
                boundaries = bwboundaries(segmentedspot,'holes');
                nbr_boundaries = size(boundaries, 1);
                for k = 1 : nbr_boundaries
                   	temp_boundary = boundaries{k};
                   	plot(temp_boundary(:,2), temp_boundary(:,1), 'r', 'LineWidth', 2);
                end
                hold off;
                saveas(gca, 'temp', 'png');
                app.extraapex = imread('temp.png');
                delete temp.png
                
                cla(app.ExtraA,'reset')
                title(app.ExtraA, 'Extra: Structure detection');
                xlabel(app.ExtraA, []);
                ylabel(app.ExtraA, []);
                app.ExtraA.XLim = [0, size(app.extraapex,2)];
                app.ExtraA.YLim = [0, size(app.extraapex,1)];
                app.ExtraA.XAxis.TickLabels = {};
                app.ExtraA.YAxis.TickLabels = {};
                imshow(app.extraapex, 'parent', app.ExtraA);
                
            elseif strcmp(value, 'Representative frame')
                % Get a struct with all the segmented frames
                segframes = struct('cdata',[]);
                for i=1:size(app.apexvideo,2)
                    temp = convert2gray(app.apexvideo(i).cdata);
                    temp = normalizeVessels(temp);
                    temp = imageEnhancement(temp);
                    temp = segmentImage(temp,0.64, 10000);
                    segframes(i).cdata = temp;
                end
                % Determine the width and height of the frames
                vidWidth = size(segframes(1).cdata, 2);
                vidHeight = size(segframes(1).cdata, 1);
                
                % Calculate the optimal frame of the video and display it in Extraaxis
                app.extraapex = pixelAverage2(segframes, vidHeight, vidWidth, 0.4);
                cla(app.ExtraA,'reset')
                title(app.ExtraA, 'Extra: Representative frame');
                app.ExtraA.XLim = [0, vidWidth];
                app.ExtraA.YLim = [0, vidHeight];
                app.ExtraA.XAxis.TickLabels = {};
                app.ExtraA.YAxis.TickLabels = {};
                imshow(app.extraapex, 'parent', app.ExtraA);
           
            end


        end

        % Value changed function: PlayvideoDropDownA
        function PlayvideoDropDownAValueChanged(app, event)
            value = app.PlayvideoDropDownA.Value;
            
            % Play original video
            if strcmp(value, 'Original video')
                if size(app.orgapexvideo,2) < 2
                    app.orgapexvideo = constructVideo(app.apexvideo);
                end
                implay(app.orgapexvideo)
                
                
                % Elseif play segmented video
            elseif strcmp(value, 'Segmented video')
                if size(app.segapexvideo,2) == 1 % if size==1 -> create video
                    for i=1:size(app.apexvideo,2)
                        temp = convert2gray(app.apexvideo(i).cdata);
                        temp = normalizeVessels(temp);
                        temp = imageEnhancement(temp);
                        temp = segmentImage(temp,0.64, 10000);
                        app.segapexvideo(i).cdata = temp;
                    end
                    app.segapexvideo = constructVideo(app.segapexvideo);
                end
                implay(app.segapexvideo)
                
                % Elseif play stabilized video
            elseif strcmp(value, 'Stabilized video')
                if size(app.stabapexvideo,2) == 1
                    app.stabapexvideo = videoStabilisation(app.apexvideo, 10, 5, 20);
                end
                implay(app.stabapexvideo)
                
            end
            
        end

        % Value changed function: SaveDropDownA
        function SaveDropDownAValueChanged(app, event)
            value = app.SaveDropDownA.Value;
            
            % Jumps to the folder where the file was loaded from
            oldfolder = cd(app.pathnameapex);
            
            if strcmp(value, 'Original image')
                if app.incOA ~= 0
                    imwrite(app.grayapex,['Original apex image' num2str(app.incOA)], 'png')
                    app.incOA = app.incOA+1;
                else
                    imwrite(app.grayapex,'Original apex image', 'png')
                    app.incOA = app.incOA+1;
                end
            
                
            elseif strcmp(value, 'Segmented image')
                normalizespot = normalizeApexes(app.grayapex);
                segmentedspot = segmentSpot(normalizespot);
                if app.incSA ~= 0
                    imwrite(segmentedspot,['Segmented apex image' num2str(app.incSA)], 'png')
                    app.incSA = app.incSA+1;
                else
                    imwrite(segmentedspot,'Segmented apex image', 'png')
                    app.incSA = app.incSA+1;
                end
                
            elseif strcmp(value, 'Extra image')
                if app.incExtraA ~= 0
                    imwrite(app.extraapex,['Extra apex image' num2str(app.incExtraA)], 'png')
                    app.incExtraA = app.incExtraA+1;
                else
                    imwrite(app.extraapex,'Extra apex image', 'png')
                    app.incExtraA = app.incExtraA+1;
                end
                
                
            end
            
            % Jumps back to existing folder
            cd(oldfolder)
            
            
        end

        % Callback function
        function ImageregistrationButtonAValueChanged(app, event)
           
        end

        % Button pushed function: ImageregistrationButtonA
        function ImageregistrationButtonAPushed(app, event)
            app.extraapex = imageStabilisation(app.apexvideo, app.FirstframeSpinnerA.Value, app.SecondframeSpinnerA.Value);

            % Plot registered frame in Extra axis
            cla(app.ExtraA,'reset')
            title(app.ExtraA, 'Extra image: Registered frame');
            xlabel(app.ExtraA, []);
            ylabel(app.ExtraA, []);
            app.ExtraA.XLim = [0, size(app.extraapex,2)];
            app.ExtraA.YLim = [0, size(app.extraapex,1)];
            app.ExtraA.XAxis.TickLabels = {};
            app.ExtraA.YAxis.TickLabels = {};
            imshow(app.extraapex, 'parent', app.ExtraA)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicroImagingTechnologyMITUIFigure = uifigure;
            app.MicroImagingTechnologyMITUIFigure.Name = 'Micro Imaging Technology - M.I.T.';
            app.MicroImagingTechnologyMITUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicroImagingTechnologyMITUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MicroImagingTechnologyMITUIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.StartCamera = uibutton(app.AcquisitionTab, 'push');
            app.StartCamera.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCamera.Text = 'Start Camera';
            app.CaptureImageButton = uibutton(app.AcquisitionTab, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.StartVideoButton = uibutton(app.AcquisitionTab, 'push');
            app.StartVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoButtonPushed, true);
            app.StartVideoButton.Text = 'Start Video';
            app.SaveButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FilenameLabel = uilabel(app.AcquisitionTab);
            app.FilenameLabel.Text = 'File name';
            app.Filename = uieditfield(app.AcquisitionTab, 'text');
            app.DirectoryButton = uibutton(app.AcquisitionTab, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory';
            app.UIAxes = uiaxes(app.AcquisitionTab);
            app.VesselsTab = uitab(app.TabGroup);
            app.VesselsTab.Title = 'Vessels';
            app.LoadvesselsButton = uibutton(app.VesselsTab, 'push');
            app.LoadvesselsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvesselsButtonPushed, true);
            app.LoadvesselsButton.Text = 'Load vessels';
            app.ChooseframeSpinnerLabel = uilabel(app.VesselsTab);
            app.ChooseframeSpinnerLabel.Text = 'Choose frame';
            app.ChooseframeSpinner = uispinner(app.VesselsTab);
            app.ChooseframeSpinner.Limits = [1 Inf];
            app.ChooseframeSpinner.ValueChangedFcn = createCallbackFcn(app, @ChooseframeSpinnerValueChanged, true);
            app.ChooseframeSpinner.Enable = 'off';
            app.ChooseframeSpinner.Value = 1;
            app.DensityofvesselsLabel = uilabel(app.VesselsTab);
            app.DensityofvesselsLabel.Text = {'Density of'; 'vessels'};
            app.DensityOfVessels = uieditfield(app.VesselsTab, 'text');
            app.DensityOfVessels.Editable = 'off';
            app.AdvanceduserSwitch = uiswitch(app.VesselsTab, 'slider');
            app.AdvanceduserSwitch.Items = {'Yes', 'No'};
            app.AdvanceduserSwitch.ValueChangedFcn = createCallbackFcn(app, @AdvanceduserSwitchValueChanged, true);
            app.AdvanceduserSwitch.Enable = 'off';
            app.AdvanceduserSwitch.Value = 'No';
            app.SensitivityEditFieldLabel = uilabel(app.VesselsTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.VesselsTab, 'numeric');
            app.SensitivityEditField.Limits = [0 1];
            app.SensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityEditFieldValueChanged, true);
            app.SensitivityEditField.Enable = 'off';
            app.SensitivityEditField.Value = 0.64;
            app.PixelremovalSliderLabel = uilabel(app.VesselsTab);
            app.PixelremovalSliderLabel.Text = {'Pixel'; 'removal'};
            app.PixelremovalSlider = uislider(app.VesselsTab);
            app.PixelremovalSlider.Limits = [0 50000];
            app.PixelremovalSlider.MajorTickLabels = {'0', '12.5k', '25k', '37.5k', '50k'};
            app.PixelremovalSlider.ValueChangedFcn = createCallbackFcn(app, @PixelremovalSliderValueChanged, true);
            app.PixelremovalSlider.Enable = 'off';
            app.PixelremovalSlider.Value = 10000;
            app.EvaluateDropDownLabel = uilabel(app.VesselsTab);
            app.EvaluateDropDownLabel.Text = 'Evaluate';
            app.EvaluateDropDown = uidropdown(app.VesselsTab);
            app.EvaluateDropDown.Items = {'-', 'De Backer''s score', 'Segmentation', 'Structure detection', 'Representative frame'};
            app.EvaluateDropDown.ValueChangedFcn = createCallbackFcn(app, @EvaluateDropDownValueChanged, true);
            app.EvaluateDropDown.Enable = 'off';
            app.EvaluateDropDown.Value = '-';
            app.PlayvideoDropDownLabel = uilabel(app.VesselsTab);
            app.PlayvideoDropDownLabel.Text = 'Play video';
            app.PlayvideoDropDown = uidropdown(app.VesselsTab);
            app.PlayvideoDropDown.Items = {'-', 'Original video', 'Enhanced video', 'Segmented video', 'Stabilized video'};
            app.PlayvideoDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayvideoDropDownValueChanged, true);
            app.PlayvideoDropDown.Enable = 'off';
            app.PlayvideoDropDown.Value = '-';
            app.SaveDropDownLabel = uilabel(app.VesselsTab);
            app.SaveDropDownLabel.Text = 'Save';
            app.SaveDropDown = uidropdown(app.VesselsTab);
            app.SaveDropDown.Items = {'-', 'Original image', 'Enhanced image', 'Segmented image', 'Extra image'};
            app.SaveDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveDropDownValueChanged, true);
            app.SaveDropDown.Enable = 'off';
            app.SaveDropDown.Value = '-';
            app.AveragediameterLabel = uilabel(app.VesselsTab);
            app.AveragediameterLabel.Text = {'Average'; 'diameter'};
            app.AvgDiameter = uitextarea(app.VesselsTab);
            app.AvgDiameter.Editable = 'off';
            app.CalculatePPVButton = uibutton(app.VesselsTab, 'push');
            app.CalculatePPVButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePPVButtonPushed, true);
            app.CalculatePPVButton.Enable = 'off';
            app.CalculatePPVButton.Text = 'Calculate PPV';
            app.TotalvesselsEditFieldLabel = uilabel(app.VesselsTab);
            app.TotalvesselsEditFieldLabel.Text = {'Total'; 'vessels'};
            app.TotalvesselsEditField = uieditfield(app.VesselsTab, 'numeric');
            app.NoflowEditFieldLabel = uilabel(app.VesselsTab);
            app.NoflowEditFieldLabel.Text = {'No'; 'flow'};
            app.NoflowEditField = uieditfield(app.VesselsTab, 'numeric');
            app.IntermittentflowEditFieldLabel = uilabel(app.VesselsTab);
            app.IntermittentflowEditFieldLabel.Text = {'Intermittent'; 'flow'};
            app.IntermittentflowEditField = uieditfield(app.VesselsTab, 'numeric');
            app.PPVTextAreaLabel = uilabel(app.VesselsTab);
            app.PPVTextAreaLabel.Text = 'PPV';
            app.PPVTextArea = uitextarea(app.VesselsTab);
            app.PPVTextArea.Editable = 'off';
            app.PVDresultTextAreaLabel = uilabel(app.VesselsTab);
            app.PVDresultTextAreaLabel.Text = {'PVD'; 'result'};
            app.PVDresultTextArea = uitextarea(app.VesselsTab);
            app.PVDresultTextArea.Editable = 'off';
            app.SNREditFieldLabel = uilabel(app.VesselsTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.VesselsTab, 'text');
            app.SNREditField.Editable = 'off';
            app.ImageregistrationButton = uibutton(app.VesselsTab, 'push');
            app.ImageregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageregistrationButtonPushed, true);
            app.ImageregistrationButton.Enable = 'off';
            app.ImageregistrationButton.Text = 'Image registration';
            app.OpticalflowButton = uibutton(app.VesselsTab, 'push');
            app.OpticalflowButton.ButtonPushedFcn = createCallbackFcn(app, @OpticalflowButtonPushed, true);
            app.OpticalflowButton.Enable = 'off';
            app.OpticalflowButton.Text = 'Optical flow';
            app.AdvanceduserLabel = uilabel(app.VesselsTab);
            app.AdvanceduserLabel.Text = 'Advanced user';
            app.Extraaxis = uiaxes(app.VesselsTab);
            app.SegmentedImg = uiaxes(app.VesselsTab);
            app.OrgImg = uiaxes(app.VesselsTab);
            ylabel(app.OrgImg, {''; ''})
            app.EnhancedImg = uiaxes(app.VesselsTab);
            app.FirstframeSpinnerLabel = uilabel(app.VesselsTab);
            app.FirstframeSpinnerLabel.Text = 'First frame';
            app.FirstframeSpinner = uispinner(app.VesselsTab);
            app.FirstframeSpinner.Limits = [1 Inf];
            app.FirstframeSpinner.Enable = 'off';
            app.FirstframeSpinner.Value = 1;
            app.SecondframeSpinnerLabel = uilabel(app.VesselsTab);
            app.SecondframeSpinnerLabel.Text = 'Second frame';
            app.SecondframeSpinner = uispinner(app.VesselsTab);
            app.SecondframeSpinner.Limits = [1 Inf];
            app.SecondframeSpinner.Enable = 'off';
            app.SecondframeSpinner.Value = 1;
            app.ApexesTab = uitab(app.TabGroup);
            app.ApexesTab.Title = 'Apexes';
            app.LoadapexesButton = uibutton(app.ApexesTab, 'push');
            app.LoadapexesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadapexesButtonPushed2, true);
            app.LoadapexesButton.Text = 'Load apexes';
            app.ChooseframeSpinner_2Label = uilabel(app.ApexesTab);
            app.ChooseframeSpinner_2Label.Text = 'Choose frame';
            app.ChooseframeSpinnerA = uispinner(app.ApexesTab);
            app.ChooseframeSpinnerA.Limits = [1 Inf];
            app.ChooseframeSpinnerA.ValueChangedFcn = createCallbackFcn(app, @ChooseframeSpinnerAValueChanged, true);
            app.ChooseframeSpinnerA.Enable = 'off';
            app.ChooseframeSpinnerA.Value = 1;
            app.EvaluateDropDown_2Label = uilabel(app.ApexesTab);
            app.EvaluateDropDown_2Label.Text = 'Evaluate';
            app.EvaluateDropDownA = uidropdown(app.ApexesTab);
            app.EvaluateDropDownA.Items = {'-', 'Segmentation', 'Structure detection', 'Representative frame'};
            app.EvaluateDropDownA.ValueChangedFcn = createCallbackFcn(app, @EvaluateDropDownAValueChanged, true);
            app.EvaluateDropDownA.Enable = 'off';
            app.EvaluateDropDownA.Value = '-';
            app.PlayvideoDropDown_2Label = uilabel(app.ApexesTab);
            app.PlayvideoDropDown_2Label.Text = 'Play video';
            app.PlayvideoDropDownA = uidropdown(app.ApexesTab);
            app.PlayvideoDropDownA.Items = {'-', 'Original video', 'Segmented video', 'Stabilized video'};
            app.PlayvideoDropDownA.ValueChangedFcn = createCallbackFcn(app, @PlayvideoDropDownAValueChanged, true);
            app.PlayvideoDropDownA.Enable = 'off';
            app.PlayvideoDropDownA.Value = '-';
            app.SaveDropDown_2Label = uilabel(app.ApexesTab);
            app.SaveDropDown_2Label.Text = 'Save';
            app.SaveDropDownA = uidropdown(app.ApexesTab);
            app.SaveDropDownA.Items = {'-', 'Original image', 'Segmented image', 'Extra image'};
            app.SaveDropDownA.ValueChangedFcn = createCallbackFcn(app, @SaveDropDownAValueChanged, true);
            app.SaveDropDownA.Enable = 'off';
            app.SaveDropDownA.Value = '-';
            app.SNREditField_2Label = uilabel(app.ApexesTab);
            app.SNREditField_2Label.Text = 'SNR';
            app.SNREditFieldA = uieditfield(app.ApexesTab, 'text');
            app.SNREditFieldA.Editable = 'off';
            app.AveragedistanceEditFieldLabel = uilabel(app.ApexesTab);
            app.AveragedistanceEditFieldLabel.Text = 'Average distance';
            app.AveragedistanceEditField = uieditfield(app.ApexesTab, 'text');
            app.AveragedistanceEditField.Editable = 'off';
            app.DensityofvesselsLabel_2 = uilabel(app.ApexesTab);
            app.DensityofvesselsLabel_2.Text = 'Density of vessels';
            app.DensityofvesselsA = uieditfield(app.ApexesTab, 'text');
            app.DensityofvesselsA.Editable = 'off';
            app.FirstframeSpinner_2Label = uilabel(app.ApexesTab);
            app.FirstframeSpinner_2Label.Text = 'First frame';
            app.FirstframeSpinnerA = uispinner(app.ApexesTab);
            app.FirstframeSpinnerA.Limits = [1 Inf];
            app.FirstframeSpinnerA.Enable = 'off';
            app.FirstframeSpinnerA.Value = 1;
            app.SecondframeSpinner_2Label = uilabel(app.ApexesTab);
            app.SecondframeSpinner_2Label.Text = 'Second frame';
            app.SecondframeSpinnerA = uispinner(app.ApexesTab);
            app.SecondframeSpinnerA.Limits = [1 Inf];
            app.SecondframeSpinnerA.Enable = 'off';
            app.SecondframeSpinnerA.Value = 1;
            app.OrgImgA = uiaxes(app.ApexesTab);
            app.CentroidsA = uiaxes(app.ApexesTab);
            ylabel(app.CentroidsA, {''; ''})
            app.SegImgA = uiaxes(app.ApexesTab);
            ylabel(app.SegImgA, {''; ''})
            app.ExtraA = uiaxes(app.ApexesTab);
            ylabel(app.ExtraA, {''; ''})
            app.ImageregistrationButtonA = uibutton(app.ApexesTab, 'push');
            app.ImageregistrationButtonA.ButtonPushedFcn = createCallbackFcn(app, @ImageregistrationButtonAPushed, true);
            app.ImageregistrationButtonA.Text = 'Image registration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MIT18

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicroImagingTechnologyMITUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicroImagingTechnologyMITUIFigure)
        end
    end
end